classdef EE_352_Lab_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        InputSinusoidxtPanel           matlab.ui.container.Panel
        AmplitudeinvoltEditFieldLabel  matlab.ui.control.Label
        AmplitudeinvoltEditField       matlab.ui.control.NumericEditField
        FrequencyinHzEditFieldLabel    matlab.ui.control.Label
        FrequencyinHzEditField         matlab.ui.control.NumericEditField
        PhaseindegreeEditFieldLabel    matlab.ui.control.Label
        PhaseindegreeEditField         matlab.ui.control.NumericEditField
        AmplitudeXtSlider              matlab.ui.control.Slider
        FrequencyXtSlider              matlab.ui.control.Slider
        PhaseXtSlider                  matlab.ui.control.Slider
        TransferFunctionofthesystemPanel  matlab.ui.container.Panel
        NsTextAreaLabel                matlab.ui.control.Label
        NsTextArea                     matlab.ui.control.TextArea
        DsTextAreaLabel                matlab.ui.control.Label
        DsTextArea                     matlab.ui.control.TextArea
        RevealButton                   matlab.ui.control.Button
        GraphPanel                     matlab.ui.container.Panel
        IOSinusoidsAxes                matlab.ui.control.UIAxes
        ComplexPlotAxes                matlab.ui.control.UIAxes
        OutputPanel                    matlab.ui.container.Panel
        AmplitudeytLabel               matlab.ui.control.Label
        AmplitudeytEditField           matlab.ui.control.NumericEditField
        PhaseytEditFieldLabel          matlab.ui.control.Label
        PhaseytEditField               matlab.ui.control.NumericEditField
        GaindBEditFieldLabel           matlab.ui.control.Label
        GaindBEditField                matlab.ui.control.NumericEditField
        PhasedegreeEditFieldLabel      matlab.ui.control.Label
        PhasedegreeEditField           matlab.ui.control.NumericEditField
        MagBodePlotAxes                matlab.ui.control.UIAxes
        PhaseBodePlotAxes              matlab.ui.control.UIAxes
        AnimationPanel                 matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        AnimateButton                  matlab.ui.control.Button
        f_startHzEditFieldLabel        matlab.ui.control.Label
        f_startHzEditField             matlab.ui.control.NumericEditField
        f_endHzEditFieldLabel          matlab.ui.control.Label
        f_endHzEditField               matlab.ui.control.NumericEditField
        dfEditFieldLabel               matlab.ui.control.Label
        dfEditField                    matlab.ui.control.NumericEditField
        dtEditFieldLabel               matlab.ui.control.Label
        dtEditField                    matlab.ui.control.NumericEditField
        current_frquencyHzEditFieldLabel  matlab.ui.control.Label
        current_frquencyHzEditField    matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        amplitude_x;
        frequency_x;
        phase_x;
        
        amplitude_y;
        frequency_y;
        phase_y;
        
        line_width = 1.0;
        system_TF;
        % system_TF = tf([1 0 1 0 2], [1 1 6 1 2 3]);
    end
    
    methods (Access = public)
        
        function [amplitude_y, phase_y] = calculate_output(app, f)
            [magnitude, phase, ~] = bode(app.system_TF, f);
            amplitude_y = magnitude(:) * app.amplitude_x;
            phase_y = app.phase_x + phase(:);
        end
        
        function [] = plot_graphs(app)
            
            cla(app.MagBodePlotAxes);
            cla(app.PhaseBodePlotAxes);
            
            amp_x = app.AmplitudeinvoltEditField.Value;
            freq = app.FrequencyinHzEditField.Value;
            ph_x = app.PhaseindegreeEditField.Value;
            
            % -------------- Input and Output Sinusoidal Plot --------------
            plot_sinusoids(app, amp_x, freq, ph_x);
            
            % ------------------- Complex Plot -----------------------
            plot_phasors(app, amp_x, freq, ph_x);
                        
            % ------------ Showing the response values ---------------
            show_response(app);
            
        end
        
        function [] = plot_bode_plots(app, freq)
            cla(app.MagBodePlotAxes);
            cla(app.PhaseBodePlotAxes);
            
            f = logspace(-2, 3, 500);
            [mag, ph, ~] = bode(app.system_TF, f);
            mag_db = 20 * log10(mag(:));
            phase = ph(:);
                       
            semilogx(app.MagBodePlotAxes, f, mag_db, 'LineWidth', app.line_width);
            grid(app.MagBodePlotAxes, 'on');
            hold(app.MagBodePlotAxes, 'on');
            
            semilogx(app.PhaseBodePlotAxes, f, phase, 'LineWidth', app.line_width);
            grid(app.PhaseBodePlotAxes, 'on');
            hold(app.PhaseBodePlotAxes, 'on');
            
            % Drop Markers
            min_mag_db = min(mag_db);
            min_ph = min(phase);
            [mag, ph, ~] = bode(app.system_TF, freq);
            mag = 20 * log10(mag);
            plot(app.MagBodePlotAxes, [freq freq min(f)], [min_mag_db mag mag], '--', 'Color', [75, 181, 54]/255, 'LineWidth', app.line_width*0.75);
            %axis(app.MagBodePlotAxes, [f(1) f(end) min_mag_db 1.1*max(mag_db)]);
            plot(app.PhaseBodePlotAxes, [freq freq min(f)], [1.5*min_ph ph ph], '--', 'Color', [75, 181, 54]/255, 'LineWidth', app.line_width*0.75);
            %axis(app.PhaseBodePlotAxes), [f(1) f(end) min_ph 1.2*max(ph)];
        end
        
        function [] = plot_sinusoids(app, amp_x, freq, ph_x)            
            cla(app.IOSinusoidsAxes);
            cla(app.ComplexPlotAxes);
            
            app.amplitude_x = amp_x;
            app.frequency_x = freq;
            app.phase_x = ph_x;
            
            [amp_y, ph_y] = calculate_output(app, freq);
            max_amplitude = max(amp_x, amp_y);
            app.amplitude_y = amp_y;
            app.frequency_y = freq;
            app.phase_y = ph_y;
                        
            dt = 1/(15*freq);
            t = -5/freq :dt: 5/freq;
            x_t = amp_x * cos(2*freq*t + ph_x*pi/180);
            y_t = amp_y * cos(2*freq*t + ph_y*pi/180);
                                 
            % Setting Axes
            axis(app.IOSinusoidsAxes, [t(1) t(end) -1.1*max_amplitude 1.1*max_amplitude]);
            
            % Plotting Axes
            plot(app.IOSinusoidsAxes, [t(1) t(end)], [0 0], '-.', 'Color', [0,0,0], 'LineWidth', 1.0);
            hold(app.IOSinusoidsAxes, 'on')
            plot(app.IOSinusoidsAxes, [0 0], [-1.1*max_amplitude 1.1*max_amplitude], '-.', 'Color', [0,0,0], 'LineWidth', 1.0);
            
            % Input Sinusoid Plot
            plot(app.IOSinusoidsAxes, t, x_t, 'Color', [0,0.5,0.9], "LineWidth", app.line_width);
            
            % Output Sinusoid Plot
            plot(app.IOSinusoidsAxes, t, y_t, 'Color', [0.9,0.5,0.0], "LineWidth", app.line_width);
        end
        
        function [] = plot_phasors(app, amp_x, freq, ph_x)
            cla(app.ComplexPlotAxes);
            
            [amp_y, ph_y] = calculate_output(app, freq);
            max_amplitude = max(amp_x, amp_y);
            
            % Some varibales to plot a unit circle
            r = 1;
            th = 0:0.01:2*pi;
            scaling_factor = 1.2;        
            
            % Plotting Axes
            plot(app.ComplexPlotAxes, [-scaling_factor*max_amplitude scaling_factor*max_amplitude], [0 0], '-.', 'Color', [0,0,0], 'LineWidth', app.line_width);
            hold(app.ComplexPlotAxes, 'on');
            plot(app.ComplexPlotAxes, [0 0], [-scaling_factor*max_amplitude +scaling_factor*max_amplitude], '-.', 'Color', [0,0,0], 'LineWidth', app.line_width);
            
            % Plotting the unit circle
            plot(app.ComplexPlotAxes, r*cos(th), r*sin(th), '--', 'Color', [0.35,0.35,0.35]);
            
            % Plotting Input Phasor 
            quiver(app.ComplexPlotAxes, 0, 0, amp_x*cosd(ph_x), amp_x*sind(ph_x), 'Color', [0,0.5,0.9], 'LineWidth', 1.25, 'MaxHeadSize', 0.5, 'AutoScale', "off");
            
            % Plotting Output Phasor 
            quiver(app.ComplexPlotAxes, 0, 0, amp_y*cosd(ph_y), amp_y*sind(ph_y), 'Color', [0.9,0.5,0.0], 'LineWidth', 1.25, 'MaxHeadSize', 0.5, 'AutoScale', "off");
            
            % Plotting response in Polar plot
            f = 0.01:0.05:100;
            [amp_y, ph_y] = calculate_output(app, f);
            plot(app.ComplexPlotAxes, amp_y.*cosd(ph_y), amp_y.*sind(ph_y));
        end
        
        function [] = show_response(app)
            app.AmplitudeytEditField.Value = app.amplitude_y;
            app.PhaseytEditField.Value = app.phase_y;
            app.GaindBEditField.Value = 20 * log10(app.amplitude_y / app.amplitude_x);
            app.PhasedegreeEditField.Value = app.phase_y - app.phase_x;
            app.current_frquencyHzEditField.Value = app.frequency_x;
            app.FrequencyXtSlider.Value = app.frequency_x;
            app.FrequencyinHzEditField.Value = app.frequency_x;
        end
        
        function [] = set_frequency_slider_limits(app)
            app.FrequencyXtSlider.Limits = [app.f_startHzEditField.Value app.f_endHzEditField.Value];
        end
        
        function N = txt_to_num(~, N_s)
            N = erase(N_s, {'[', ']'});
            N = split(N, {' ', ','});
            N = str2double(N)';
        end
        
        function [N, D] = generate_transfer_function(app)
            N = txt_to_num(app, app.NsTextArea.Value);
            D = txt_to_num(app, app.DsTextArea.Value);
            app.system_TF = tf(N, D);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % plot(app.IOSinusoidsAxes, [0 1], [0 0], 'Color', [0,0,0]);
            % plot(app.IOSinusoidsAxes, [0 0], [1.1 1.1], 'Color', [0,0,0]);
            app.amplitude_x = app.AmplitudeinvoltEditField.Value;
            app.frequency_x = app.FrequencyinHzEditField.Value;
            app.phase_x = app.PhaseindegreeEditField.Value;
            
            set_frequency_slider_limits(app);
        end

        % Button pushed function: RevealButton
        function RevealButtonPushed(app, event)
            generate_transfer_function(app);
            
            app.amplitude_x = app.AmplitudeinvoltEditField.Value;
            app.frequency_x = app.FrequencyinHzEditField.Value;
            app.phase_x = app.PhaseindegreeEditField.Value;
            
            plot_graphs(app);
            plot_bode_plots(app, app.frequency_x);
        end

        % Value changed function: AmplitudeinvoltEditField
        function AmplitudeinvoltEditFieldValueChanged(app, event)
            value = app.AmplitudeinvoltEditField.Value;
            app.AmplitudeXtSlider.Value = value;
            RevealButtonPushed(app);
        end

        % Value changed function: FrequencyinHzEditField
        function FrequencyinHzEditFieldValueChanged(app, event)
            value = app.FrequencyinHzEditField.Value;
            app.FrequencyXtSlider.Value = value;
            RevealButtonPushed(app);
        end

        % Value changed function: PhaseindegreeEditField
        function PhaseindegreeEditFieldValueChanged(app, event)
            value = app.PhaseindegreeEditField.Value;
            app.PhaseXtSlider.Value = value;
            RevealButtonPushed(app);
        end

        % Value changed function: AmplitudeXtSlider
        function AmplitudeXtSliderValueChanged(app, event)
            value = app.AmplitudeXtSlider.Value;
            app.AmplitudeinvoltEditField.Value = value;
            RevealButtonPushed(app);
        end

        % Value changed function: FrequencyXtSlider
        function FrequencyXtSliderValueChanged(app, event)
            value = app.FrequencyXtSlider.Value;
            app.FrequencyinHzEditField.Value = value;
            RevealButtonPushed(app);
        end

        % Value changed function: PhaseXtSlider
        function PhaseXtSliderValueChanged(app, event)
            value = app.PhaseXtSlider.Value;
            app.PhaseindegreeEditField.Value = value;
            RevealButtonPushed(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.IOSinusoidsAxes);
            cla(app.ComplexPlotAxes);
            cla(app.MagBodePlotAxes);
            cla(app.PhaseBodePlotAxes);
            % grid(app.MagBodePlotAxes, 'off');
            % grid(app.PhaseBodePlotAxes, 'off');
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            f_start = app.f_startHzEditField.Value;
            df = app.dfEditField.Value;
            f_end = app.f_endHzEditField.Value;
            dt_animation = app.dtEditField.Value;
            
            f = f_start:df:f_end;
            
            %ResetButtonPushed(app);
                        
            for i=1:length(f)
                plot_sinusoids(app, app.amplitude_x, f(i), app.phase_x);
                plot_phasors(app, app.amplitude_x, f(i), app.phase_x);
                plot_bode_plots(app, f(i));
                show_response(app);
                pause(dt_animation);
            end
            disp("Done");
            
        end

        % Value changed function: f_startHzEditField
        function f_startHzEditFieldValueChanged(app, event)
            set_frequency_slider_limits(app);
        end

        % Value changed function: f_endHzEditField
        function f_endHzEditFieldValueChanged(app, event)
            set_frequency_slider_limits(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.InputSinusoidxtPanel = uipanel(app.InputPanel);
            app.InputSinusoidxtPanel.Title = 'Input Sinusoid (x(t))';
            app.AmplitudeinvoltEditFieldLabel = uilabel(app.InputSinusoidxtPanel);
            app.AmplitudeinvoltEditFieldLabel.Text = 'Amplitude (in volt) : ';
            app.AmplitudeinvoltEditField = uieditfield(app.InputSinusoidxtPanel, 'numeric');
            app.AmplitudeinvoltEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeinvoltEditFieldValueChanged, true);
            app.AmplitudeinvoltEditField.Value = 1;
            app.FrequencyinHzEditFieldLabel = uilabel(app.InputSinusoidxtPanel);
            app.FrequencyinHzEditFieldLabel.Text = 'Frequency (in Hz) : ';
            app.FrequencyinHzEditField = uieditfield(app.InputSinusoidxtPanel, 'numeric');
            app.FrequencyinHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyinHzEditFieldValueChanged, true);
            app.FrequencyinHzEditField.Value = 1;
            app.PhaseindegreeEditFieldLabel = uilabel(app.InputSinusoidxtPanel);
            app.PhaseindegreeEditFieldLabel.Text = 'Phase (in degree) : ';
            app.PhaseindegreeEditField = uieditfield(app.InputSinusoidxtPanel, 'numeric');
            app.PhaseindegreeEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseindegreeEditFieldValueChanged, true);
            app.AmplitudeXtSlider = uislider(app.InputSinusoidxtPanel);
            app.AmplitudeXtSlider.Limits = [0 10];
            app.AmplitudeXtSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeXtSliderValueChanged, true);
            app.FrequencyXtSlider = uislider(app.InputSinusoidxtPanel);
            app.FrequencyXtSlider.Limits = [0.01 100];
            app.FrequencyXtSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyXtSliderValueChanged, true);
            app.FrequencyXtSlider.Value = 0.01;
            app.PhaseXtSlider = uislider(app.InputSinusoidxtPanel);
            app.PhaseXtSlider.Limits = [-180 180];
            app.PhaseXtSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseXtSliderValueChanged, true);
            app.TransferFunctionofthesystemPanel = uipanel(app.InputPanel);
            app.TransferFunctionofthesystemPanel.Title = 'Transfer Function of the system';
            app.NsTextAreaLabel = uilabel(app.TransferFunctionofthesystemPanel);
            app.NsTextAreaLabel.Text = 'N(s) : ';
            app.NsTextArea = uitextarea(app.TransferFunctionofthesystemPanel);
            app.NsTextArea.Value = {'[1 0]'};
            app.DsTextAreaLabel = uilabel(app.TransferFunctionofthesystemPanel);
            app.DsTextAreaLabel.Text = 'D(s) : ';
            app.DsTextArea = uitextarea(app.TransferFunctionofthesystemPanel);
            app.DsTextArea.Value = {'[1 1]'};
            app.RevealButton = uibutton(app.InputPanel, 'push');
            app.RevealButton.ButtonPushedFcn = createCallbackFcn(app, @RevealButtonPushed, true);
            app.RevealButton.Text = 'Reveal';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph Panel';
            app.IOSinusoidsAxes = uiaxes(app.GraphPanel);
            title(app.IOSinusoidsAxes, {'Input and Output Sinusoids'; ''})
            xlabel(app.IOSinusoidsAxes, 't (time)')
            ylabel(app.IOSinusoidsAxes, 'x(t) and y(t)')
            app.ComplexPlotAxes = uiaxes(app.GraphPanel);
            title(app.ComplexPlotAxes, 'Complex Plot')
            xlabel(app.ComplexPlotAxes, 'Real Axis')
            ylabel(app.ComplexPlotAxes, {'Imaginary Axis'; ''})
            app.ComplexPlotAxes.XTickLabel = {'-1.2'; '0.5'; '1.2'};
            app.ComplexPlotAxes.YTickLabel = {'-1.2'; '0'; '1.2'};
            app.OutputPanel = uipanel(app.GraphPanel);
            app.OutputPanel.Title = 'Output Panel';
            app.AmplitudeytLabel = uilabel(app.OutputPanel);
            app.AmplitudeytLabel.Text = 'Amplitude (y(t) ) : ';
            app.AmplitudeytEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PhaseytEditFieldLabel = uilabel(app.OutputPanel);
            app.PhaseytEditFieldLabel.Text = 'Phase (y(t) ) : ';
            app.PhaseytEditField = uieditfield(app.OutputPanel, 'numeric');
            app.GaindBEditFieldLabel = uilabel(app.OutputPanel);
            app.GaindBEditFieldLabel.Text = 'Gain (dB) : ';
            app.GaindBEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PhasedegreeEditFieldLabel = uilabel(app.OutputPanel);
            app.PhasedegreeEditFieldLabel.Text = 'Phase (degree) : ';
            app.PhasedegreeEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MagBodePlotAxes = uiaxes(app.GraphPanel);
            title(app.MagBodePlotAxes, 'Magnitude response')
            xlabel(app.MagBodePlotAxes, 'f (frequency)')
            ylabel(app.MagBodePlotAxes, 'gain (db) = 20 * log10(gain)')
            app.PhaseBodePlotAxes = uiaxes(app.GraphPanel);
            title(app.PhaseBodePlotAxes, 'Phase response')
            xlabel(app.PhaseBodePlotAxes, 'f (frequency)')
            ylabel(app.PhaseBodePlotAxes, 'Phase')
            app.AnimationPanel = uipanel(app.UIFigure);
            app.AnimationPanel.Title = 'Animation Panel';
            app.ResetButton = uibutton(app.AnimationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AnimateButton = uibutton(app.AnimationPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.f_startHzEditFieldLabel = uilabel(app.AnimationPanel);
            app.f_startHzEditFieldLabel.Text = 'f_start (Hz) : ';
            app.f_startHzEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.f_startHzEditField.ValueChangedFcn = createCallbackFcn(app, @f_startHzEditFieldValueChanged, true);
            app.f_startHzEditField.Value = 0.01;
            app.f_endHzEditFieldLabel = uilabel(app.AnimationPanel);
            app.f_endHzEditFieldLabel.Text = 'f_end (Hz) : ';
            app.f_endHzEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.f_endHzEditField.ValueChangedFcn = createCallbackFcn(app, @f_endHzEditFieldValueChanged, true);
            app.f_endHzEditField.Value = 7;
            app.dfEditFieldLabel = uilabel(app.AnimationPanel);
            app.dfEditFieldLabel.Text = 'df : ';
            app.dfEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.dfEditField.Value = 0.08;
            app.dtEditFieldLabel = uilabel(app.AnimationPanel);
            app.dtEditFieldLabel.Text = 'dt : ';
            app.dtEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.dtEditField.Value = 0.0001;
            app.current_frquencyHzEditFieldLabel = uilabel(app.AnimationPanel);
            app.current_frquencyHzEditFieldLabel.Text = 'current_frquency (Hz): ';
            app.current_frquencyHzEditField = uieditfield(app.AnimationPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE_352_Lab_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end